classdef gainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GainLabel                  matlab.ui.control.Label
        ParametersPanel            matlab.ui.container.Panel
        EnterGaindBEditFieldLabel  matlab.ui.control.Label
        GainValue                  matlab.ui.control.EditField
        OptionsPanel               matlab.ui.container.Panel
        CancelButton               matlab.ui.control.Button
        SaveExitButton             matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        gain
        i
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, i)
            app.i = i;
            gain_flag = 0;
            assignin("base",'gain_flag',gain_flag);    
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq();
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            app.gain =db2mag(str2double(app.GainValue.Value));
            cont_str = sprintf("gain_%d",app.i);
            assignin("base",cont_str,app.gain);
            gain_flag = 1;
            assignin("base",'gain_flag',gain_flag);
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GainLabel = uilabel(app.UIFigure);
            app.GainLabel.Text = 'Gain';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.EnterGaindBEditFieldLabel = uilabel(app.ParametersPanel);
            app.EnterGaindBEditFieldLabel.Text = 'Enter Gain [dB] :';
            app.GainValue = uieditfield(app.ParametersPanel, 'text');
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gainGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end