classdef loopShapingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoopShapingGUI                  matlab.ui.Figure
        ControllerPanel                 matlab.ui.container.Panel
        LeadLag                         matlab.ui.control.Button
        Notch                           matlab.ui.control.Button
        Gain                            matlab.ui.control.Button
        PID                             matlab.ui.control.Button
        AddPolesZeros                   matlab.ui.control.Button
        AutomaticDesigner               matlab.ui.control.Button
        ResetContoller                  matlab.ui.control.Button
        ControllerText                  matlab.ui.control.Label
        CLBode                          matlab.ui.control.Button
        Nichols                         matlab.ui.control.Button
        CurrentController               matlab.ui.control.Label
        ControllerList                  matlab.ui.control.Table
        OpenLoopBodeButton              matlab.ui.control.Button
        EditControllerEditFieldLabel    matlab.ui.control.Label
        EditCont_num                    matlab.ui.control.EditField
        EditButton                      matlab.ui.control.Button
        DeleteControllerEditFieldLabel  matlab.ui.control.Label
        DelCont_num                     matlab.ui.control.EditField
        DeleteButton                    matlab.ui.control.Button
        NicholsBodePanel                matlab.ui.container.Panel
        nicholsPlot                     matlab.ui.control.UIAxes
        ControllerPolesandZerosPanel    matlab.ui.container.Panel
        zpkMap                          matlab.ui.control.UIAxes
        zpkTable_p                      matlab.ui.control.Table
        zpkTable_z                      matlab.ui.control.Table
        OptionsPanel                    matlab.ui.container.Panel
        SaveController                  matlab.ui.control.Button
        LoadController                  matlab.ui.control.Button
        LoadPlant                       matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        Discrete                        matlab.ui.control.Button
        savenExit                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        LoopShapingGUILabel             matlab.ui.control.Label
        QFTLogo                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        G = evalin('base','G'); % Description
        des = evalin("base",'des');
        leadLag_counter = 0;
        gain_counter = 0;
        pid_counter = 0;
        notch_counter = 0;
    end
    
    methods (Access = private)
        
        function [] = updateGnic(app,G,des)
            Lnom = series(des.nom,G);
            t_color = des.col;
            plot(app.nicholsPlot,Lnom.phase,Lnom.mag,'k','LineWidth',1.2);
            hold(app.nicholsPlot,"on")
            for k=1:length(des.tpl)
                str = sprintf(' %g',des.tpl(k).frequency);
                tk = qfr(des.tpl(k).template(1),des.tpl(k).frequency);
                Ltpl = series(tk,G);
                x_text = Ltpl.phase;
                y_text = Ltpl.mag;
                plot(app.nicholsPlot,Ltpl.phase,Ltpl.mag,'marker','square',...
                    'markeredgecolor','k','markerfacecolor',t_color(k,:));
                text(app.nicholsPlot,x_text,y_text,str,'clipping','on') % single space added
            end
            hold(app.nicholsPlot,"off")
        end
        
        function [] = printPZmap(app,G)
            [p,z] = pzmap(G);
            
            if  ~isempty(p)
                plot(app.zpkMap,real(p),imag(p),'xr')
                hold(app.zpkMap,'on')
            end
            
            if ~isempty(z)
                plot(app.zpkMap,real(z),imag(z),'ob')
                hold(app.zpkMap,'on')
            end
            
            plot(app.zpkMap,[-50 50],[0 0],'k','LineWidth',0.5)
            hold(app.zpkMap,'on')
            plot(app.zpkMap,[0 0],[-50 50],'k','LineWidth',0.5)
            hold(app.zpkMap,'off')
            app.zpkMap.Title.String = "Pole - Zero Map";
            app.zpkMap.XLabel.String = "Real";
            app.zpkMap.YLabel.String = "Imaginary";
            
            %fill pz table
            app.zpkTable_p.Data = {};
            app.zpkTable_z.Data = {};
            if ~isempty(p)
                for i=1:length(p)
                    sys_poles(i,:) = {'pole' real(p(i)) imag(p(i))}; %sample data for each column
                end
                app.zpkTable_p.Data = [app.zpkTable_p.Data(:);sys_poles];
            end
            
            if ~isempty(z)
                for j=1:length(z)
                    sys_zeros(j,:) = {'zero' real(z(j)) imag(z(j))}; %sample data for each column
                end
                
                app.zpkTable_z.Data = [app.zpkTable_z.Data(:);sys_zeros];
            end
        end
        
        function [] = printHSbnd(app,des)
            sepcs_num = length(des.bnd);
            freq_num = length(des.bnd(1).w);
            
            for i = 1 : 1 : sepcs_num
                for j = 1 : 1 : freq_num
                    bounds_data = des.bnd(i).c;
                    print_bound = bounds_data{j,1};
                    plot(app.nicholsPlot,real(print_bound),imag(print_bound),'LineWidth',1.3)
                    hold(app.nicholsPlot,'on')
                end
            end
            
            ngrid(app.nicholsPlot)
            app.nicholsPlot.Title.String = "Nichols Plot";
            app.nicholsPlot.XLabel.String = "Phase [deg]";
            app.nicholsPlot.YLabel.String = "Mag [dB]";
            hold(app.nicholsPlot,"on")
        end
        
        function [] = printGtext(app,G)
            Gtf = tf(G);
            syms s
            [num_P,den_P] = tfdata(Gtf);
            Gsym = vpa(poly2sym((num_P),s)/poly2sym((den_P),s),2);
            Gstr = print_sym_fraction(Gsym);
            app.CurrentController.Text = Gstr;
        end
        
        function [] = updateAll(app,G,des)
            printHSbnd(app,des);
            updateGnic(app,G,des);
            printPZmap(app,G);
            printGtext(app,G);
        end
        
        function [] = printCLbode(app,G,des)
            app.nicholsPlot.cla
            wnom = des.nom.frequency;
            Cwnom = squeeze(freqresp(G,wnom)); % in complex plain
            Fwnom = squeeze(freqresp(tf(1),wnom)); % in complex plain
            Pnom = n2c(des.nom.response);
            Lnom = Cwnom.*Pnom;
            Tnom = Fwnom.*Lnom./(1+Lnom);
            T_mag = mag2db(abs(Tnom));
            semilogx(app.nicholsPlot,wnom,T_mag)
            app.nicholsPlot.Title.String = "Close Loop Bode";
            app.nicholsPlot.XLabel.String = "Frequency [rad/s]";
            app.nicholsPlot.XGrid = 'On';
            app.nicholsPlot.YGrid = 'On';
        end
        
        function [] = printCONTlist(app,leadlag_counter,gain_counter,pid_counter,notch_counter)
            %reset tavble
            app.ControllerList.Data = {};
            
            % update the controller's list - lead lag
            if leadlag_counter ~= 0
                for i = 1 : leadlag_counter
                    table_str(i,:) = {i convertStringsToChars(sprintf("Clead_%d",i)) 'lead/lag'};
                end
            end
            
            % update the controller's list - gain
            if gain_counter ~= 0
                for i = 1 : gain_counter
                    table_str(leadlag_counter+i,:) = {leadlag_counter+i convertStringsToChars(sprintf("gain_%d",i)) 'gain'};
                end
            end
            
            % update the controller's list - pid
            if pid_counter ~= 0
                for i = 1 : pid_counter
                    table_str(leadlag_counter+gain_counter+i,:) = {leadlag_counter+gain_counter+i convertStringsToChars(sprintf("pid_%d",i)) 'PID'};
                end
            end
            
            % update the controller's list - notch
            if notch_counter ~= 0
                for i = 1 : notch_counter
                    table_str(leadlag_counter+gain_counter+pid_counter+i,:) = {leadlag_counter+gain_counter+pid_counter+i convertStringsToChars(sprintf("notch_%d",i)) 'notch'};
                end
            end
            app.ControllerList.Data = [app.ControllerList.Data(:);table_str];
            
        end
        
        function [] = printOLbode(app,G,des)
            wnom = des.nom.frequency;
            Cwnom = squeeze(freqresp(G,wnom)); % in complex plain
            Fwnom = squeeze(freqresp(tf(1),wnom)); % in complex plain
            Pnom = n2c(des.nom.response);
            Lnom = Cwnom.*Pnom;
            L_mag = mag2db(abs(Lnom));
            L_phase = rad2deg(angle(Lnom));
            figure();
            subplot(2,1,1)
            semilogx(wnom,L_mag)
            grid("minor")
            xlabel('Frequency [rad/sec]')
            ylabel('Mag [dB]')
            title('Open Loop Mag')
            
            subplot(2,1,2)
            semilogx(wnom,L_phase)
            grid minor
            xlabel('Frequency [rad/sec]')
            ylabel('Phase [deg]')
            title('Open Loop Phase')
            set(gcf,'color','w')
        end
        
        
        function Controller = updateController(app,G,leadlag_counter,gain_counter,pid_counter,notch_counter,str_num)
            clead_cont = 1;
            gain_cont = 1;
            notch_cont = 1;
            pid_cont = 1;
            
            if leadlag_counter ~= 0
                for i = 1 : leadlag_counter
                    if i ~= str_num
                     cont_str = sprintf("Clead_%d",i);
                     clead = evalin("base",cont_str);
                     clead_cont = clead * clead_cont;
                    end
                end
            end
            
            if gain_counter ~= 0
                for i = 1 : gain_counter
                    cont_str = sprintf("gain_%d",i);
                    gain = evalin("base",cont_str);
                    if ~isempty(gain)
                        gain_cont = gain * gain_cont;
                    end
                end
            end
            
            if pid_counter ~= 0
                for i = 1 : pid_counter
                    cont_str = sprintf("pid_%d",i);
                    pid = evalin("base",cont_str);
                    if ~isempty(pid)
                        pid_cont = pid * pid_cont;
                    end
                end
            end
            
            if notch_counter ~= 0
                for i = 1 : notch_counter
                    cont_str = sprintf("notch_%d",i);
                    notch = evalin("base",cont_str);
                    if ~isempty(notch)
                        notch_cont = notch * notch_cont;
                    end
                end
            end
            Controller = G * clead_cont * gain_cont * pid_cont * notch_cont;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %print the controller on text
            printGtext(app,app.G);
            
            %print pz map
            printPZmap(app,app.G);
            
            %print H-S bounds and the open loop on nichols chart
            printHSbnd(app,app.des);
            
            %plotting the open loop
            updateGnic(app,app.G,app.des)
        end

        % Button pushed function: LoadPlant
        function LoadPlantButtonPushed(app, event)
            
        end

        % Button pushed function: ResetContoller
        function ResetContollerButtonPushed(app, event)
            % reset controller
            app.G = tf(1);
            
            %reset graphs and tables
            app.nicholsPlot.cla
            app.zpkMap.cla
            app.ControllerList.Data = {};
            
            %update graphs with the reseted conrtoller
            updateAll(app,app.G,app.des);
            
            %reset conrtollers counters
            app.leadLag_counter = 0;
            app.gain_counter = 0;
            app.pid_counter = 0;
            app.notch_counter = 0;
            
        end

        % Button pushed function: CLBode
        function CLBodeButtonPushed(app, event)
            app.nicholsPlot.cla
            printCLbode(app,app.G,app.des);
        end

        % Button pushed function: Nichols
        function NicholsButtonPushed(app, event)
            app.nicholsPlot.cla;
            printHSbnd(app,app.des);
            updateGnic(app,app.G,app.des);
        end

        % Button pushed function: LeadLag
        function LeadLagButtonPushed(app, event)
            
            % if pressed for the first time set counter to 1. if not add
            % advance counter by 1
            if app.leadLag_counter == 0
                app.leadLag_counter = 1;
            else
                app.leadLag_counter = app.leadLag_counter+1;
            end
            
            % open controller GUI and wait until it close
            waitfor(leadLagGUI(app.leadLag_counter));
            
            % get the controller for w.s
            leadlag_flag = evalin("base",'leadlag_flag');
            
            %if the user pressed save&exit add the conrtoller to G, update
            %controller list and update all graphs
            if leadlag_flag == 1
                get_controller_str = sprintf("Clead_%d",app.leadLag_counter);
                clead = evalin("base",get_controller_str);
                % connect clead in series with G
                app.G = app.G*clead;
                
                %update controller's list
                printCONTlist(app,app.leadLag_counter,app.gain_counter,app.pid_counter,app.notch_counter)
                
                % update all plots and maps
                updateAll(app,app.G,app.des);
            else
                app.leadLag_counter = app.leadLag_counter - 1;
            end
        end

        % Button pushed function: Gain
        function GainButtonPushed(app, event)
            % if pressed for the first time or advance counter
            if app.gain_counter == 0
                app.gain_counter = 1;
            else
                app.gain_counter = app.gain_counter+1;
            end
            
            % open controller GUI
            waitfor(gainGUI(app.gain_counter));
            
            % get the controller for w.s
            gain_flag = evalin("base",'gain_flag');
            
            if gain_flag == 1
                get_controller_str = sprintf("gain_%d",app.gain_counter);
                gain = evalin("base",get_controller_str);
                %connect gain in series with G
                app.G = app.G*gain;
                
                %update controller's list
                printCONTlist(app,app.leadLag_counter,app.gain_counter,app.pid_counter,app.notch_counter)
                
                % update all plots and maps
                updateAll(app,app.G,app.des);
            else
                app.gain_counter = app.gain_counter - 1;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq()
        end

        % Button pushed function: savenExit
        function savenExitButtonPushed(app, event)
            assignin("base","G",app.G)
            closereq()
        end

        % Button pushed function: OpenLoopBodeButton
        function OpenLoopBodeButtonPushed(app, event)
            printOLbode(app,app.G,app.des);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            str_num = str2double(app.DelCont_num.Value);
            str_num = int8(str_num);
            str = convertCharsToStrings(app.ControllerList.Data(str_num,2));
            
            del_str = sprintf("clear %s",str);
            evalin("base",del_str);
            
            app.G = updateController(app,app.G,app.leadLag_counter,app.gain_counter,app.pid_counter,app.notch_counter,str_num);
            updateAll(app,app.G,app.des);
            app.ControllerList.Data(str_num,:) = {'' '' ''};
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            str_num = str2double(app.EditCont_num.Value);
            str_num = int8(str_num);
            type = convertCharsToStrings(app.ControllerList.Data(str_num,3));
            
            switch type
                case 'lead/lag'
                    waitfor(leadLagGUI(str_num))
                    % get the controller for w.s
                    leadlag_flag = evalin("base",'leadlag_flag');
                    %if the user pressed save&exit add the conrtoller to G, update
                    %controller list and update all graphs
                    if  leadlag_flag == 1
                        get_controller_str = sprintf("Clead_%d",str_num);
                        clead = evalin("base",get_controller_str);
                        % connect clead in series with G
                        app.G = app.G*clead;
                        % update all plots and maps
                        updateAll(app,app.G,app.des);
                    end
                case 'gain'
                    waitfor(gainGUI(str_num))
                    gain_flag = evalin("base",'gain_flag');
                    if gain_flag == 1
                        get_controller_str = sprintf("gain_%d",str_num);
                        gain = evalin("base",get_controller_str);
                        app.G = app.G*gain;
                        updateAll(app,app.G,app.des);
                    end
                case 'pid'
                    %add commands
                case 'notch'
                    %add commands
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoopShapingGUI = uifigure('Visible', 'off');
            app.LoopShapingGUI.Name = 'UI Figure';
            app.ControllerPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPanel.Title = 'Controller';
            app.LeadLag = uibutton(app.ControllerPanel, 'push');
            app.LeadLag.ButtonPushedFcn = createCallbackFcn(app, @LeadLagButtonPushed, true);
            app.LeadLag.Text = 'Lead/Lag';
            app.Notch = uibutton(app.ControllerPanel, 'push');
            app.Notch.Text = 'Notch/anti Notch';
            app.Gain = uibutton(app.ControllerPanel, 'push');
            app.Gain.ButtonPushedFcn = createCallbackFcn(app, @GainButtonPushed, true);
            app.Gain.Text = 'Gain';
            app.PID = uibutton(app.ControllerPanel, 'push');
            app.PID.Text = 'PID';
            app.AddPolesZeros = uibutton(app.ControllerPanel, 'push');
            app.AddPolesZeros.Text = 'Add Poles/Zeros';
            app.AutomaticDesigner = uibutton(app.ControllerPanel, 'push');
            app.AutomaticDesigner.Text = 'Automatic Designer';
            app.ResetContoller = uibutton(app.ControllerPanel, 'push');
            app.ResetContoller.ButtonPushedFcn = createCallbackFcn(app, @ResetContollerButtonPushed, true);
            app.ResetContoller.Text = 'Reset Contoller';
            app.ControllerText = uilabel(app.ControllerPanel);
            app.ControllerText.Text = 'Current Contoller:';
            app.CLBode = uibutton(app.ControllerPanel, 'push');
            app.CLBode.ButtonPushedFcn = createCallbackFcn(app, @CLBodeButtonPushed, true);
            app.CLBode.Text = 'Close Loop Bode';
            app.Nichols = uibutton(app.ControllerPanel, 'push');
            app.Nichols.ButtonPushedFcn = createCallbackFcn(app, @NicholsButtonPushed, true);
            app.Nichols.Text = 'Nichols';
            app.CurrentController = uilabel(app.ControllerPanel);
            app.CurrentController.Text = 'G(s) = 1';
            app.ControllerList = uitable(app.ControllerPanel);
            app.ControllerList.ColumnName = {'#'; 'Name'; 'Type'};
            app.ControllerList.RowName = {};
            app.OpenLoopBodeButton = uibutton(app.ControllerPanel, 'push');
            app.OpenLoopBodeButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLoopBodeButtonPushed, true);
            app.OpenLoopBodeButton.Text = 'Open Loop Bode';
            app.EditControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.EditControllerEditFieldLabel.Text = 'Edit Controller #:';
            app.EditCont_num = uieditfield(app.ControllerPanel, 'text');
            app.EditButton = uibutton(app.ControllerPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.DeleteControllerEditFieldLabel.Text = 'Delete Controller #:';
            app.DelCont_num = uieditfield(app.ControllerPanel, 'text');
            app.DeleteButton = uibutton(app.ControllerPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.NicholsBodePanel = uipanel(app.LoopShapingGUI);
            app.NicholsBodePanel.Title = 'Nichols / Bode';
            app.nicholsPlot = uiaxes(app.NicholsBodePanel);
            title(app.nicholsPlot, 'Title')
            xlabel(app.nicholsPlot, 'X')
            ylabel(app.nicholsPlot, 'Y')
            app.ControllerPolesandZerosPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPolesandZerosPanel.Title = 'Controller Poles and Zeros';
            app.zpkMap = uiaxes(app.ControllerPolesandZerosPanel);
            title(app.zpkMap, 'Title')
            xlabel(app.zpkMap, 'X')
            ylabel(app.zpkMap, 'Y')
            app.zpkTable_p = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_p.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_p.RowName = {''; ''; ''};
            app.zpkTable_p.ColumnEditable = true;
            app.zpkTable_z = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_z.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_z.RowName = {''; ''; ''};
            app.zpkTable_z.ColumnEditable = true;
            app.OptionsPanel = uipanel(app.LoopShapingGUI);
            app.OptionsPanel.Title = 'Options';
            app.SaveController = uibutton(app.OptionsPanel, 'push');
            app.SaveController.Text = 'Save Controller To Workspace';
            app.LoadController = uibutton(app.OptionsPanel, 'push');
            app.LoadController.Text = 'Load Controller From Workspace';
            app.LoadPlant = uibutton(app.OptionsPanel, 'push');
            app.LoadPlant.ButtonPushedFcn = createCallbackFcn(app, @LoadPlantButtonPushed, true);
            app.LoadPlant.Text = 'Load Plant From Workspace';
            app.Help = uibutton(app.OptionsPanel, 'push');
            app.Help.Text = 'Help!';
            app.Discrete = uibutton(app.OptionsPanel, 'push');
            app.Discrete.Text = 'Discrete Design';
            app.savenExit = uibutton(app.OptionsPanel, 'push');
            app.savenExit.ButtonPushedFcn = createCallbackFcn(app, @savenExitButtonPushed, true);
            app.savenExit.Text = 'Save Controller to Workspace and Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoopShapingGUILabel = uilabel(app.LoopShapingGUI);
            app.LoopShapingGUILabel.Text = 'Loop Shaping GUI';
            app.QFTLogo = uiimage(app.LoopShapingGUI);
            app.QFTLogo.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.LoopShapingGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loopShapingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoopShapingGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoopShapingGUI)
        end
    end
end