classdef pidGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        StandartPIDTab        matlab.ui.container.Tab
        Title                 matlab.ui.control.Label
        ParametersPanel       matlab.ui.container.Panel
        EnterButton           matlab.ui.control.Button
        KpLabel               matlab.ui.control.Label
        Kp                    matlab.ui.control.EditField
        KiLabel               matlab.ui.control.Label
        Ki                    matlab.ui.control.EditField
        KdLabel               matlab.ui.control.Label
        Kd                    matlab.ui.control.EditField
        TimeConstantsLabel    matlab.ui.control.Label
        Tf                    matlab.ui.control.EditField
        ReadyLampLabel        matlab.ui.control.Label
        Ready                 matlab.ui.control.Lamp
        OptionsPanel          matlab.ui.container.Panel
        HelpButton            matlab.ui.control.Button
        SaveExitButton        matlab.ui.control.Button
        CancelButton          matlab.ui.control.Button
        ContollerBodePanel    matlab.ui.container.Panel
        Bode_mag              matlab.ui.control.UIAxes
        Bode_phase            matlab.ui.control.UIAxes
        kpkiskdsTfs1Label     matlab.ui.control.Label
        sPIDTab_s             matlab.ui.container.Tab
        Title_s               matlab.ui.control.Label
        ParametersPanel_s     matlab.ui.container.Panel
        EnterButton_s         matlab.ui.control.Button
        KpLabel_2             matlab.ui.control.Label
        Kp_s                  matlab.ui.control.EditField
        KiLabel_2             matlab.ui.control.Label
        Ki_s                  matlab.ui.control.EditField
        KdLabel_2             matlab.ui.control.Label
        Kd_s                  matlab.ui.control.EditField
        TimeConstantsLabel_2  matlab.ui.control.Label
        Tf_s                  matlab.ui.control.EditField
        Tau_dsecLabel         matlab.ui.control.Label
        Taud_s                matlab.ui.control.EditField
        ReadyLampLabel_2      matlab.ui.control.Label
        Ready_s               matlab.ui.control.Lamp
        OptionsPanel_s        matlab.ui.container.Panel
        HelpButton_s          matlab.ui.control.Button
        SaveExitButton_s      matlab.ui.control.Button
        CancelButton_s        matlab.ui.control.Button
        ContollerBodePanel_s  matlab.ui.container.Panel
        Bode_mag_s            matlab.ui.control.UIAxes
        Bode_phase_s          matlab.ui.control.UIAxes
        kp1kistaudsLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        G
        des
        kp;
        kp_s;
        kd;
        kd_s;
        ki;
        ki_s;
        tf;
        tf_s;
        taud;
        edit_flag; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, G, des, EditMode)
           app.CallingApp = mainapp;
           app.G = G;
           app.des = des;
           app.edit_flag = EditMode;     
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            cpid = CPID(str2double(app.Ki.Value),str2double(app.Kp.Value),str2double(app.Kd.Value),str2double(app.Tf.Value),[],0);
            [mag, phase_local, w0] = bode(cpid);
            semilogx(app.Bode_mag,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase,w0,squeeze(phase_local(1,1,:)));
            app.kp = str2double(app.Kp.Value);
            app.ki = str2double(app.Ki.Value);
            app.kd = str2double(app.Kd.Value);
            app.tf = str2double(app.Tf.Value);
            
            if ~isempty(app.kp) && ~isempty(app.ki) && ~isempty(app.kd) && ~isempty(app.tf)
                app.Ready.Color = [0,1,0];
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            pid_flag = 0;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tf,[],0,pid_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: SaveExitButton
        function SaveExitButtonPushed(app, event)
            pid_flag = 1;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp,app.ki,app.kd,app.tf,[],0,pid_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: EnterButton_s
        function EnterButton_sPushed(app, event)
            cpid = CPID(str2double(app.Ki_s.Value),str2double(app.Kp_s.Value),str2double(app.Kd_s.Value),str2double(app.Tf_s.Value),str2double(app.Taud_s.Value),1);
            [mag, phase_local, w0] = bode(cpid);
            semilogx(app.Bode_mag_s,w0,squeeze(mag(1,1,:)));
            semilogx(app.Bode_phase_s,w0,squeeze(phase_local(1,1,:)));
            app.kp_s = str2double(app.Kp_s.Value);
            app.ki_s = str2double(app.Ki_s.Value);
            app.kd_s = str2double(app.Kd_s.Value);
            app.tf_s = str2double(app.Tf_s.Value);
            app.taud = str2double(app.Taud_s);
            
            if ~isempty(app.kp_s) && ~isempty(app.ki_s) && ~isempty(app.kd_s) && ~isempty(app.tf_s) && ~isempty(app.taud)
                app.Ready_s.Color = [0,1,0];
            end
        end

        % Button pushed function: CancelButton_s
        function CancelButton_sPushed(app, event)
            pid_flag = 0;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,app.ki_s,app.kd_s,app.tf_s,app.taud,1,pid_flag,app.edit_flag)
            delete(app);
        end

        % Button pushed function: SaveExitButton_s
        function SaveExitButton_sPushed(app, event)
            pid_flag = 1;
            updatePidCont(app.CallingApp,app.G,app.des,app.kp_s,app.ki_s,app.kd_s,app.tf_s,app.taud,1,pid_flag,app.edit_flag)
            delete(app);   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StandartPIDTab = uitab(app.TabGroup);
            app.StandartPIDTab.Title = 'Standart P.I.D';
            app.Title = uilabel(app.StandartPIDTab);
            app.Title.Text = 'Standard PID Controller';
            app.ParametersPanel = uipanel(app.StandartPIDTab);
            app.ParametersPanel.Title = 'Parameters';
            app.EnterButton = uibutton(app.ParametersPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.KpLabel = uilabel(app.ParametersPanel);
            app.KpLabel.Text = 'Kp:';
            app.Kp = uieditfield(app.ParametersPanel, 'text');
            app.KiLabel = uilabel(app.ParametersPanel);
            app.KiLabel.Text = 'Ki:';
            app.Ki = uieditfield(app.ParametersPanel, 'text');
            app.KdLabel = uilabel(app.ParametersPanel);
            app.KdLabel.Text = 'Kd:';
            app.Kd = uieditfield(app.ParametersPanel, 'text');
            app.TimeConstantsLabel = uilabel(app.ParametersPanel);
            app.TimeConstantsLabel.Text = 'Time Constant [s]:';
            app.Tf = uieditfield(app.ParametersPanel, 'text');
            app.ReadyLampLabel = uilabel(app.ParametersPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.Ready = uilamp(app.ParametersPanel);
            app.OptionsPanel = uipanel(app.StandartPIDTab);
            app.OptionsPanel.Title = 'Options';
            app.HelpButton = uibutton(app.OptionsPanel, 'push');
            app.HelpButton.Text = 'Help';
            app.SaveExitButton = uibutton(app.OptionsPanel, 'push');
            app.SaveExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButtonPushed, true);
            app.SaveExitButton.Text = 'Save & Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ContollerBodePanel = uipanel(app.StandartPIDTab);
            app.ContollerBodePanel.Title = 'Contoller Bode';
            app.Bode_mag = uiaxes(app.ContollerBodePanel);
            title(app.Bode_mag, 'Mag')
            xlabel(app.Bode_mag, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag, 'Mag')
            app.Bode_phase = uiaxes(app.ContollerBodePanel);
            title(app.Bode_phase, 'Phase')
            xlabel(app.Bode_phase, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase, 'Phase')
            app.kpkiskdsTfs1Label = uilabel(app.StandartPIDTab);
            app.kpkiskdsTfs1Label.Text = 'kp+ki/s+(kd*s)/(Tf*s+1)';
            app.sPIDTab_s = uitab(app.TabGroup);
            app.sPIDTab_s.Title = ' Time Deign P.I.D';
            app.Title_s = uilabel(app.sPIDTab_s);
            app.Title_s.Text = 'Time Design PID Controller';
            app.ParametersPanel_s = uipanel(app.sPIDTab_s);
            app.ParametersPanel_s.Title = 'Parameters';
            app.EnterButton_s = uibutton(app.ParametersPanel_s, 'push');
            app.EnterButton_s.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_sPushed, true);
            app.EnterButton_s.Text = 'Enter';
            app.KpLabel_2 = uilabel(app.ParametersPanel_s);
            app.KpLabel_2.Text = 'Kp:';
            app.Kp_s = uieditfield(app.ParametersPanel_s, 'text');
            app.KiLabel_2 = uilabel(app.ParametersPanel_s);
            app.KiLabel_2.Text = 'Ki:';
            app.Ki_s = uieditfield(app.ParametersPanel_s, 'text');
            app.KdLabel_2 = uilabel(app.ParametersPanel_s);
            app.KdLabel_2.Text = 'Kd:';
            app.Kd_s = uieditfield(app.ParametersPanel_s, 'text');
            app.TimeConstantsLabel_2 = uilabel(app.ParametersPanel_s);
            app.TimeConstantsLabel_2.Text = 'Time Constant [s]:';
            app.Tf_s = uieditfield(app.ParametersPanel_s, 'text');
            app.Tau_dsecLabel = uilabel(app.ParametersPanel_s);
            app.Tau_dsecLabel.Text = 'Tau_d [sec]:';
            app.Taud_s = uieditfield(app.ParametersPanel_s, 'text');
            app.ReadyLampLabel_2 = uilabel(app.ParametersPanel_s);
            app.ReadyLampLabel_2.Text = 'Ready';
            app.Ready_s = uilamp(app.ParametersPanel_s);
            app.OptionsPanel_s = uipanel(app.sPIDTab_s);
            app.OptionsPanel_s.Title = 'Options';
            app.HelpButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.HelpButton_s.Text = 'Help';
            app.SaveExitButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.SaveExitButton_s.ButtonPushedFcn = createCallbackFcn(app, @SaveExitButton_sPushed, true);
            app.SaveExitButton_s.Text = 'Save & Exit';
            app.CancelButton_s = uibutton(app.OptionsPanel_s, 'push');
            app.CancelButton_s.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_sPushed, true);
            app.CancelButton_s.Text = 'Cancel';
            app.ContollerBodePanel_s = uipanel(app.sPIDTab_s);
            app.ContollerBodePanel_s.Title = 'Contoller Bode';
            app.Bode_mag_s = uiaxes(app.ContollerBodePanel_s);
            title(app.Bode_mag_s, 'Mag')
            xlabel(app.Bode_mag_s, 'Frequency [rad/sec]')
            ylabel(app.Bode_mag_s, 'Mag')
            app.Bode_phase_s = uiaxes(app.ContollerBodePanel_s);
            title(app.Bode_phase_s, 'Phase')
            xlabel(app.Bode_phase_s, 'Frequency [rad/sec]')
            ylabel(app.Bode_phase_s, 'Phase')
            app.kp1kistaudsLabel = uilabel(app.sPIDTab_s);
            app.kp1kistaudsLabel.Text = 'kp(1+ki/s+taud*s)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pidGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end